classdef Gradient_Descent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        StepsizeSliderLabel          matlab.ui.control.Label
        StepsizeSlider               matlab.ui.control.Slider
        StartingpointPanel           matlab.ui.container.Panel
        X0EditFieldLabel             matlab.ui.control.Label
        x01_value                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        x02_value                    matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        FunctionPanel                matlab.ui.container.Panel
        fonction_expression          matlab.ui.control.Label
        FunctionchoiceDropDownLabel  matlab.ui.control.Label
        Functionchoice               matlab.ui.control.DropDown
        text25                       matlab.ui.control.Label
        text26                       matlab.ui.control.Label
        alphatxt                     matlab.ui.control.NumericEditField
        Change2D3DviewButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        affichage_axes_values
        % Description
        affichage_value % Description
    end
    
    methods (Access = private)
        
        %%% Functions to minimize %%%

        % Quadratic function / Sphere function
        function Z = quadratic_plot(app, X, Y)
            Z = X.^2 + 2*Y.^2;
        end
        
        function [fx, dfx] = quadratic_function(app, x)
            fx = x(1)^2 + 2*x(2)^2;
            dfx = [2*x(1);4*x(2)];
        end
        
        % Rosenbrock Function / Banana function
        function [fx,dfx] = rosenbrock_function(app, x)
            a = 1;
            b = 100;
            fx = (a-x(1))^2 + b * (x(2)-x(1)^2)^2;
            dfx = [-2*(a-x(1))+2*b*(x(2)-x(1)^2)*(-2*x(1)) ; b*2*(x(2)-x(1)^2)];
        end

        function [Z] = rosenbrock_plot(app, X, Y)
            a = 1;
            b = 100;
            Z = (a-X).^2 + b * (Y-X.^2).^2;
        end
        
        % Himmelblau's function
        function [fx,dfx] = himmelblau_function(app, x)
            fx = (x(1)^2+x(2)-11)^2+(x(1)+x(2)^2-7)^2;
            dfx = [4*x(1)*(x(1)^2+x(2)-11)+2*(x(1)+x(2)^2-7);
                   2*(x(1)^2+x(2)-11)+4*x(2)*(x(1)+x(2)^2-7)];
        end

        function [Z] = himmelblau_plot(app, X, Y)
            Z = (X.^2+Y-11).^2+(X+Y.^2-7).^2;
        end
        
        function update_plot(app)
            x0 = [app.x01_value.Value; app.x02_value.Value];
            alpha = app.StepsizeSlider.Value;
            
            if strcmp(app.Functionchoice.Value, 'Quadratic')
                obj_function = @(x) app.quadratic_function(x);
            elseif strcmp(app.Functionchoice.Value, 'Rosenbrock')
                obj_function = @(x) app.rosenbrock_function(x);
            elseif strcmp(app.Functionchoice.Value, 'Himmelblau')
                obj_function = @(x) app.himmelblau_function(x);
            end
            % Start Gradient Descent algorithm
            [sol, points, f_values] = gradient_descent(app, obj_function, x0, alpha);
            
            % Clear axes, we replot everything
            cla(app.UIAxes)
            
            % Plot GD path
            if ~app.affichage_value
                plot(app.UIAxes, [x0(1) points(1, :)], [x0(2) points(2, :)], 'r', 'LineStyle', '-', 'Marker', 'o', "MarkerFaceColor", 'r', 'MarkerSize', 4);
                hold(app.UIAxes,'on')
                % Add starting point marker
                plot(app.UIAxes,x0(1),x0(2),'r+','MarkerSize',10);
                view(app.UIAxes, 2)
            else
                plot3(app.UIAxes, [x0(1) points(1, :)], [x0(2) points(2, :)], f_values, 'r', 'LineStyle', '-', 'Marker', 'o', "MarkerFaceColor", 'r', 'MarkerSize', 4);
                hold(app.UIAxes,'on')
                plot3(app.UIAxes, x0(1), x0(2), f_values(1), 'r+', 'MarkerSize', 10);
                view(app.UIAxes, 15, 50)
                grid(app.UIAxes,'on')
            end
            
            % Plot contour according to user choice
        
            if strcmp(app.Functionchoice.Value, 'Quadratic')
                if ~app.affichage_value
                    contour(app.UIAxes, app.affichage_axes_values.X1, app.affichage_axes_values.Y1, app.affichage_axes_values.Z1, ...
                        'LevelList', [0.6 5 20 49.64 95.37 147.7 197.5 256.6 302.6 352 404.5])
                    plot(app.UIAxes, 0, 0, 'k+', 'MarkerSize', 10);
                    plot(app.UIAxes, sol(1), sol(2), 'ko', 'MarkerSize', 10);
                    grid(app.UIAxes,'off')
                    view(app.UIAxes, 2)
                else
                    mesh(app.UIAxes, app.affichage_axes_values.X1, app.affichage_axes_values.Y1, app.affichage_axes_values.Z1)
                    plot3(app.UIAxes, 0, 0, 0, 'k+', 'MarkerSize', 15);
                    plot3(app.UIAxes, sol(1), sol(2), f_values(end), 'ko', 'MarkerSize', 10);
                    view(app.UIAxes, 15, 50)
                    zlim(app.UIAxes, app.affichage_axes_values.Z_limits.ZL1);
                    grid(app.UIAxes,'on')
                end
                xlim(app.UIAxes, [-20,20]);
                ylim(app.UIAxes, [-5,5]);
                hold(app.UIAxes,'off')
                
            elseif strcmp(app.Functionchoice.Value, 'Rosenbrock')
                if ~app.affichage_value
                    contour(app.UIAxes, app.affichage_axes_values.X2, app.affichage_axes_values.Y2, app.affichage_axes_values.Z2, ...
                        'LevelList', [0.1,3,5,10,20,50,200,450,1500])
                    plot(app.UIAxes, sol(1), sol(2), 'ko', 'MarkerSize', 10);
                    plot(app.UIAxes, 1, 1, 'k+', 'MarkerSize', 10);
                    grid(app.UIAxes, 'off')
                    view(app.UIAxes, 2)
                else
                    mesh(app.UIAxes, app.affichage_axes_values.X2, app.affichage_axes_values.Y2, app.affichage_axes_values.Z2)
                    plot3(app.UIAxes, 1, 1, 0,'k+','MarkerSize',15);
                    plot3(app.UIAxes, sol(1), sol(2), f_values(end), 'ko', 'MarkerSize', 10);
                    view(app.UIAxes, 15, 50)
                    zlim(app.UIAxes, app.affichage_axes_values.Z_limits.ZL2);
                    grid(app.UIAxes,'on')
                end
                xlim(app.UIAxes,[-2,2]);
                ylim(app.UIAxes,[-1,3]);
                hold(app.UIAxes,'off')
            elseif strcmp(app.Functionchoice.Value, 'Himmelblau')
                if ~app.affichage_value
                    contour(app.UIAxes, app.affichage_axes_values.X3, app.affichage_axes_values.Y3, app.affichage_axes_values.Z3, ...
                        'LevelList', [0.1,3,5,10,20,50,200,450,1500])
                    plot(app.UIAxes, sol(1), sol(2), 'ko', 'MarkerSize', 10);
                    plot(app.UIAxes, 3, 2, 'k+', 'MarkerSize', 10);
                    plot(app.UIAxes, -2.805118, 3.283186,'k+','MarkerSize',10);
                    plot(app.UIAxes, -3.779310, -3.283186,'k+','MarkerSize',10);
                    plot(app.UIAxes, 3.584458, -1.848126, 'k+','MarkerSize',10);
                    grid(app.UIAxes, 'off')
                    view(app.UIAxes, 2)
                else
                    mesh(app.UIAxes, app.affichage_axes_values.X3, app.affichage_axes_values.Y3, app.affichage_axes_values.Z3)
                    plot3(app.UIAxes, 3, 2, 0,'k+','MarkerSize',15);
                    plot3(app.UIAxes, -2.805118, 3.283186, 0,'k+','MarkerSize',15);
                    plot3(app.UIAxes, -3.779310, -3.283186, 0,'k+','MarkerSize',15);
                    plot3(app.UIAxes, 3.584458, -1.848126, 0, 'k+','MarkerSize',15);
                    
                    plot3(app.UIAxes, sol(1), sol(2), f_values(end), 'ko', 'MarkerSize', 10);
                    view(app.UIAxes, 15, 50)
                    zlim(app.UIAxes, app.affichage_axes_values.Z_limits.ZL3);
                    grid(app.UIAxes,'on')
                end
                xlim(app.UIAxes,[-5,5]);
                ylim(app.UIAxes,[-5,5]);
                hold(app.UIAxes,'off')
            end
            box(app.UIAxes,'on')

        end
        
        %%% Gradien Descent Function %%%
        function [sol, points, function_values] = gradient_descent(app, obj_function, x0, alpha)

                Nmax = 500;
                points = zeros(2,Nmax);
                function_values = zeros(1,Nmax+1);
                xcurr = x0;
                [fx,dfx] = obj_function(xcurr);
                function_values(:,1) = fx;
                for i = 1: Nmax
                    xnew = xcurr - alpha*dfx;
                    points(:,i) = xnew;
                    xcurr = xnew;
                    [fx,dfx] = obj_function(xcurr);
                    function_values(:,i+1) = fx;
                end
                sol = xnew;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.affichage_value = 0 ;
            
            x1 = -20:0.1:20;
            y1 = -5:0.1:5;
            [X1,Y1] = meshgrid(x1,y1);
            Z1 = app.quadratic_plot(X1,Y1);
            app.affichage_axes_values.X1 = X1;
            app.affichage_axes_values.Y1 = Y1;
            app.affichage_axes_values.Z1 = Z1;

            x2 = -2:0.01:2;
            y2 = -1:0.01:3;
            [X2,Y2] = meshgrid(x2,y2);
            Z2 = app.rosenbrock_plot(X2,Y2);
            app.affichage_axes_values.X2 = X2;
            app.affichage_axes_values.Y2 = Y2;
            app.affichage_axes_values.Z2 = Z2;

            x3 = -5:0.01:5;
            y3 = -5:0.01:5;
            [X3,Y3] = meshgrid(x3, y3);
            Z3 = app.himmelblau_plot(X3,Y3);
            app.affichage_axes_values.X3 = X3;
            app.affichage_axes_values.Y3 = Y3;
            app.affichage_axes_values.Z3 = Z3;
            
            app.affichage_axes_values.Z_limits.ZL1 = [min(min(app.affichage_axes_values.Z1)) max(max(app.affichage_axes_values.Z1))];
            app.affichage_axes_values.Z_limits.ZL2 = [min(min(app.affichage_axes_values.Z2)) max(max(app.affichage_axes_values.Z2))];
            app.affichage_axes_values.Z_limits.ZL3 = [min(min(app.affichage_axes_values.Z3)) max(max(app.affichage_axes_values.Z3))];
            
            hold(app.UIAxes, 'on')
            xlim(app.UIAxes, [-20,20]);
            ylim(app.UIAxes, [-5,5]);
            contour(app.UIAxes, app.affichage_axes_values.X1, app.affichage_axes_values.Y1, app.affichage_axes_values.Z1,...
                'LevelList', [0.6 5 20 49.64 95.37 147.7 197.5 256.6 302.6 352 404.5]);
            
            % Get starting point
            x0 = [app.x01_value.Value; app.x02_value.Value];
            
            plot(app.UIAxes, x0(1), x0(2), 'r+', 'MarkerSize', 10);
            plot(app.UIAxes, 0, 0, 'k+', 'MarkerSize', 10);
            app.alphatxt.Value = 0.25;
            update_plot(app)

        end

        % Value changed function: StepsizeSlider
        function StepsizeSliderValueChanged(app, event)
            % Display rounded value "with valeur_alpha_txt"
            if strcmp(app.Functionchoice.Value, 'Quadratic')
                app.alphatxt.Value = round(app.StepsizeSlider.Value, 2);
            elseif strcmp(app.Functionchoice.Value, 'Rosenbrock')
                app.alphatxt.Value = round(app.StepsizeSlider.Value, 4);
            elseif strcmp(app.Functionchoice.Value, 'Himmelblau')
                app.alphatxt.Value = round(app.StepsizeSlider.Value, 4);

            end
            update_plot(app)
        end

        % Value changed function: Functionchoice
        function FunctionchoiceValueChanged(app, event)
            % Change slider min / max values and texts
            if strcmp(app.Functionchoice.Value, 'Quadratic')
                app.StepsizeSlider.Limits = [0, 0.5];
                app.StepsizeSlider.Value = 0.25;
                app.alphatxt.Value = 0.25;
                app.fonction_expression.Text = 'f(x,y) = x² + 2·y²';
%                 title(app.UIAxes2, '$$ f_(x,y) = x² + 2·y² $$',"Interpreter","latex")
                app.x01_value.Value = -4.5;
                app.x02_value.Value = 4.5;
                app.StepsizeSlider.MajorTicks = [0, 0.25, 0.5];
                app.StepsizeSlider.MajorTickLabels = {'0', '0.25', '0.5'};
            elseif strcmp(app.Functionchoice.Value, 'Rosenbrock')
                app.StepsizeSlider.Limits = [0, 0.005];
                app.fonction_expression.Text = 'f(x,y) = (1-x)² + 100·(y-x²)²';
                app.x01_value.Value = -1.5;
                app.x02_value.Value = 1.5;
                app.StepsizeSlider.MajorTicks = [0, 0.0025, 0.005];
                app.StepsizeSlider.MajorTickLabels = {'0', '0.0025', '0.005'};
                app.StepsizeSlider.Value = 0.0025;
                app.alphatxt.Value = 0.0025;
            elseif strcmp(app.Functionchoice.Value, 'Himmelblau')
                app.StepsizeSlider.Limits = [0, 0.02];
                app.fonction_expression.Text = 'f(x,y) = (x²+y-11)²+(x+y²-7)²';
                app.x01_value.Value = 4;
                app.x02_value.Value = 4;
                app.StepsizeSlider.MajorTicks = [0, 0.01, 0.02];
                app.StepsizeSlider.MajorTickLabels = {'0', '0.01', '0.02'};
                app.StepsizeSlider.Value = 0.01;
                app.alphatxt.Value = 0.01; 
            end
            
            update_plot(app)
        end

        % Value changed function: alphatxt
        function alphatxtValueChanged(app, event)
            value = app.alphatxt.Value;
            app.StepsizeSlider.Value = value;
            update_plot(app)
        end

        % Button pushed function: Change2D3DviewButton
        function Change2D3DviewButtonPushed(app, event)
            app.affichage_value = ~app.affichage_value;
            update_plot(app)
        end

        % Value changed function: x01_value
        function x01_valueValueChanged(app, event)
            update_plot(app)
        end

        % Value changed function: x02_value
        function x02_valueValueChanged(app, event)
            update_plot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StepsizeSliderLabel = uilabel(app.UIFigure);
            app.StepsizeSliderLabel.Text = 'Step size';
            app.StepsizeSlider = uislider(app.UIFigure);
            app.StepsizeSlider.Limits = [0 0.5];
            app.StepsizeSlider.MajorTicks = [0 0.25 0.5];
            app.StepsizeSlider.MajorTickLabels = {'0', '0.25', '0.5'};
            app.StepsizeSlider.ValueChangedFcn = createCallbackFcn(app, @StepsizeSliderValueChanged, true);
            app.StepsizeSlider.Value = 0.25;
            app.StartingpointPanel = uipanel(app.UIFigure);
            app.StartingpointPanel.Title = 'Starting point';
            app.X0EditFieldLabel = uilabel(app.StartingpointPanel);
            app.X0EditFieldLabel.Text = 'X0 = (';
            app.x01_value = uieditfield(app.StartingpointPanel, 'numeric');
            app.x01_value.ValueChangedFcn = createCallbackFcn(app, @x01_valueValueChanged, true);
            app.x01_value.Value = -4.5;
            app.EditFieldLabel = uilabel(app.StartingpointPanel);
            app.EditFieldLabel.Text = ',';
            app.x02_value = uieditfield(app.StartingpointPanel, 'numeric');
            app.x02_value.ValueChangedFcn = createCallbackFcn(app, @x02_valueValueChanged, true);
            app.x02_value.Value = 4.5;
            app.Label = uilabel(app.StartingpointPanel);
            app.Label.Text = ')';
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Title = 'Function';
            app.fonction_expression = uilabel(app.FunctionPanel);
            app.fonction_expression.Text = {'f(x,y) = x² + 2·y²'; ''; ''; ''};
            app.FunctionchoiceDropDownLabel = uilabel(app.FunctionPanel);
            app.FunctionchoiceDropDownLabel.Text = 'Function choice';
            app.Functionchoice = uidropdown(app.FunctionPanel);
            app.Functionchoice.Items = {'Quadratic', 'Rosenbrock', 'Himmelblau'};
            app.Functionchoice.ValueChangedFcn = createCallbackFcn(app, @FunctionchoiceValueChanged, true);
            app.Functionchoice.Value = 'Quadratic';
            app.text25 = uilabel(app.UIFigure);
            app.text25.Text = 'Gradient Descent Examples';
            app.text26 = uilabel(app.UIFigure);
            app.text26.Text = '© Carvalho Esteban | Pedagogical use';
            app.alphatxt = uieditfield(app.UIFigure, 'numeric');
            app.alphatxt.ValueChangedFcn = createCallbackFcn(app, @alphatxtValueChanged, true);
            app.Change2D3DviewButton = uibutton(app.UIFigure, 'push');
            app.Change2D3DviewButton.ButtonPushedFcn = createCallbackFcn(app, @Change2D3DviewButtonPushed, true);
            app.Change2D3DviewButton.Text = 'Change 2D / 3D view';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gradient_Descent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end